classdef SignalRenamingTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalRenameTool             matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        IndividualSignalRenamingTab  matlab.ui.container.Tab
        OldSignalNameEditFieldLabel  matlab.ui.control.Label
        OldSignalNameEditField       matlab.ui.control.EditField
        NewSignalNameEditFieldLabel  matlab.ui.control.Label
        NewSignalNameEditField       matlab.ui.control.EditField
        RenameSignalNameButton       matlab.ui.control.Button
        ResetModuleButton_3          matlab.ui.control.Button
        EditField                    matlab.ui.control.EditField
        TextArea                     matlab.ui.control.TextArea
        GroupSignalRenamingTab       matlab.ui.container.Tab
        ExcelSheetNameLabel          matlab.ui.control.Label
        ExcelSheetNameEditField      matlab.ui.control.EditField
        RenameAllSignalsButton       matlab.ui.control.Button
        NoteTextAreaLabel            matlab.ui.control.Label
        NoteTextArea                 matlab.ui.control.TextArea
        EditField_2                  matlab.ui.control.EditField
        ResetModuleButton_2          matlab.ui.control.Button
        TextArea_3                   matlab.ui.control.TextArea
        SignalPropagationPanel       matlab.ui.container.Panel
        PropagateSignalNamesButton   matlab.ui.control.Button
        EditField_3                  matlab.ui.control.EditField
        TextArea_2                   matlab.ui.control.TextArea
    end


    properties (Access = public)
        oldstring;%old String value to be renamed.
        newstring;%new string value to be renamed. 
        xcelName;%Input for the exccel sheet.
    end


    methods (Access = private)

        % Value changed function: ExcelSheetNameEditField
        function ExcelSheetNameEditFieldValueChanged(app, event)
            app.xcelName = app.ExcelSheetNameEditField.Value;            
        end

        % Value changed function: NewSignalNameEditField
        function NewSignalNameEditFieldValueChanged(app, event)
            value = app.NewSignalNameEditField.Value;
            app.newstring = value;
        end

        % Value changed function: OldSignalNameEditField
        function OldSignalNameEditFieldValueChanged(app, event)
            value = app.OldSignalNameEditField.Value;
            app.oldstring = value;
        end

        % Button pushed function: RenameAllSignalsButton
        function RenameAllSignalsButtonPushed(app, event)
           
            try
            filename = (app.ExcelSheetNameEditField.Value);
            [~,OrgNameLst,~] = xlsread(filename,'Sheet1','A:A');
            [~,NewNameLst,~] = xlsread(filename,'Sheet1','B:B');
            NoSigLst = {};
            Cnt = 0;
            %number of signals in both the couloumns shoud be matched
            if length(OrgNameLst) == length(NewNameLst)
                signallines = find_system(gcs, 'FindAll', 'on', 'type', 'line');
                for i = 1:length(signallines)
                     set(signallines(i),'signalPropagation','off');
                end
                
                for j = 1 : length(OrgNameLst)  
                    NoGrpSl(j)  = 0;
                    NoGrpFm(j)  = 0;
                    NoGrpGt(j)  = 0;
                    NoGrpMexpl(j) = 0;
                    NoGrpTr(j)  = 0;
                    NoGrpSt(j) = 0;
                 %Finding and replacing the signal names for all the ports in the propogation	  
                     myreqport = find_system(gcs,'FindAll','on','FollowLinks','On','regexp','on','Name',OrgNameLst{j});
                     if ~isempty(myreqport)               
                        for k = 1 : length(myreqport)
                            NoGrpSl(j) = 0; 
                            set_param(myreqport(k),'Name',NewNameLst{j});
                        end
                     else
                            NoGrpSl(j) = 1;
                     end    
                 
                     %Finding and replacing from blocks for new naming convention
                     fromblocks = find_system(gcs,'FindAll','on','FollowLinks','On','regexp','on','BlockType','From');
           
                     for i = 1 : length(fromblocks)
                        fromtag = get_param(fromblocks(i),'GotoTag');
                        if strcmp(fromtag,OrgNameLst{j})
                             NoGrpFm(j) = 0;
                             set_param(fromblocks(i),'GotoTag',NewNameLst{j})
                        else
                             NoGrpFm(j) = 1;
                        end
                     end
                 
                     %Finding and replacing goto blocks for the new naming convention
                     gotoblocks = find_system(gcs,'FindAll','on','FollowLinks','On','regexp','on','BlockType','Goto');
                     for i = 1 : length(gotoblocks)
                        gototag = get_param(gotoblocks(i),'GotoTag');
                        if strcmp(gototag,OrgNameLst{j})
                            NoGrpGt(j) = 0;
                            set_param(gotoblocks(i),'GotoTag',NewNameLst{j})
                        else
                            NoGrpGt(j) = 1;
                        end    
                     end
                     
                     %Finding all the corresponding signals in stateflow charts and renaming 
                     rt=sfroot;
                     m = rt.find('-isa', 'Simulink.BlockDiagram', '-and','Name',gcs);
                     chartnames = find_system(gcs,'MaskType', 'Stateflow');
                     for i = 1 : length(chartnames)
                        chartname = chartnames{i};
                    	allports  = get_param(chartname,'Blocks');
                    	% allports = allports{1};
                    	for k = 1 : length(allports)                
                    		if strcmp(allports{k},OrgNameLst{j})
                                        NoGrpMexpl = 0;
                    			chartname = strsplit(chartname,'/');
                    			chartname = chartname{end};
                    			ch = m.find('-isa','Stateflow.Chart','-and','Name',chartname);
                    			chData = ch.find('-isa', 'Stateflow.Data','-and','Name',OrgNameLst{j});
                    			set(chData,'Name',NewNameLst{j});
                                else
                                        NoGrpMexpl = 1;
                    		end	
                    	end		
                     end     
                    rt = sfroot;
                    m = rt.find('-isa', 'Simulink.BlockDiagram', '-and','Name',gcs);
                    charts = m.find('-isa','Stateflow.Chart');
                    for k = 1 : length(charts)
                    %Replacing the strings in all the transitions
                    ch = charts(k);
                    Tr = ch.find('-isa','Stateflow.Transition');
                        for i = 1 : length(Tr)
                            if strfind(Tr(i).LabelString,OrgNameLst{j})
                                NoGrpTr(j) = 0;
                                Tr(i).LabelString = strrep( Tr(i).LabelString ,OrgNameLst{j},NewNameLst{j});
                            else
                                NoGrpTr(j) = 1;
                            end
                        end    
                    %Replacing the strings in all the state actions
                    St = ch.find('-isa','Stateflow.State');
                        for i = 1 :length(St)
                            if strfind(St(i).LabelString,OrgNameLst{j})
                                NoGrpSt(j) = 0;
                                St(i).LabelString = strrep(St(i).LabelString,OrgNameLst{j},NewNameLst{j});
                            else
                                NoGrpSt(j) = 1;
                            end
                        end 
                    end
                    if ( NoGrpSl(j) && NoGrpFm(j) && NoGrpGt(j) && NoGrpMexpl(j) && ...
                         NoGrpTr(j) && NoGrpSt(j))
                         Cnt = Cnt+ 1;
                         NoSigLst{Cnt} = OrgNameLst{j};%getting the signalnames of not present elements
                    end 
                end
                if  isempty(NoSigLst)
                    app.EditField_2.value = 'Renamed successfully';
                    app.TextArea_3.Value = 'All Signal Names have been renamed';
                    
                elseif length(OrgNameLst) == length(NoSigLst)
                    app.EditField_2.Value = 'Renamed successfully';
                    str = 'The following signals are not found in the model:';
                    for dispctr = 1 : length(NoSigLst)
                        str = [str newline num2str(dispctr) '.' NoSigLst{dispctr}];
                    end
                    app.TextArea_3.Value = str;
                else
                   app.EditField_2.Value = 'Error!!';
                   app.TextArea_3.Value = 'No Signals in the list is found in the model';
                end   
           else
                app.EditField_2.Value = 'Error!!'; 
                app.EditField_6.Value = 'Mismatch between number of signals in old and new signal name list';
           end
           catch e
            app.EditField_2.Value = 'Error!!';
            app.TextArea_3.Value = e.message;
           end
        end

        % Button pushed function: RenameSignalNameButton
        function RenameSignalNameButtonPushed(app, event)
            
            Nosl = 0;
	    NoGt = 0;
            NoFm = 0;
            NoMexpl = 0;
            NoTr = 0;
            NoSt = 0;
            
            try
            signallines = find_system(gcs, 'FindAll', 'on', 'type', 'line');
            for i = 1:length(signallines)
                set(signallines(i),'signalPropagation','off');
            end
     
            %Finding and replacing the signal names for all the ports in the propogation	  
            myreqport = find_system(gcs,'FindAll','on','FollowLinks','On','regexp','on','Name',app.oldstring);
            
            if ~isempty(myreqport)
            for k = 1 : length(myreqport)             
                set_param(myreqport(k),'Name',app.newstring);
                Nosl = 0;
            end
            else
                Nosl = 1;
            end    
     
             %Finding and replacing from blocks for new naming convention
             fromblocks = find_system(gcs,'FindAll','on','FollowLinks','On','regexp','on','BlockType','From');
             for i = 1 : length(fromblocks)
                fromtag = get_param(fromblocks(i),'GotoTag');
                if strcmp(fromtag,app.oldstring)
                     set_param(fromblocks(i),'GotoTag',app.newstring);
                     NoFm = 0;
                else
                     NoFm = 1;
                end                
             end
             
             %Finding and replacing goto blocks for the new naming convention
             gotoblocks = find_system(gcs,'FindAll','on','FollowLinks','On','regexp','on','BlockType','Goto');
             for i = 1 : length(gotoblocks)
                gototag = get_param(gotoblocks(i),'GotoTag');
                if strcmp(gototag,app.oldstring)
                    set_param(gotoblocks(i),'GotoTag',app.newstring);
                    NoGt = 0;
                else
                    NoGt = 1;
                end
             end
     
             %Finding all the corresponding signals in stateflow charts and renaming 
             rt=sfroot;
             m = rt.find('-isa', 'Simulink.BlockDiagram', '-and','Name',gcs);
             chartnames = find_system(gcs,'MaskType', 'Stateflow');
             for i = 1 : length(chartnames)
                chartname = chartnames{i};
            	allports  = get_param(chartname,'Blocks');
            	% allports = allports{1};
            	for j = 1 : length(allports)
            		if strcmp(allports{j},app.oldstring)
                                NoMexpl = 0;
            			chartname = strsplit(chartname,'/');
            			chartname = chartname{end};
            			ch = m.find('-isa','Stateflow.Chart','-and','Name',chartname);
            			chData = ch.find('-isa', 'Stateflow.Data','-and','Name',app.oldstring);
            			set(chData,'Name',app.newstring);
                        else
                                NoMexpl = 1;
            		end	
            	end		
             end 
            rt = sfroot;
            m = rt.find('-isa', 'Simulink.BlockDiagram', '-and','Name',gcs);
            charts = m.find('-isa','Stateflow.Chart');
            for k = 1 : length(charts)
            %Replacing the strings in all the transitions
            ch = charts(k);
            Tr = ch.find('-isa','Stateflow.Transition');
            for i = 1 : length(Tr)
                if strfind(Tr(i).LabelString,app.oldstring)
                    NoTr = 0;
                    Tr(i).LabelString = strrep( Tr(i).LabelString ,app.oldstring,app.newstring);
                else
                    NoTr = 1;
                end
            end    
            %Replcing the strings in all the state actions
            St = ch.find('-isa','Stateflow.State');
            for i = 1 :length(St)
                if strfind(St(i).LabelString,app.oldstring)
                    NoSt = 0;
                    St(i).LabelString = strrep(St(i).LabelString,app.oldstring,app.newstring);
                else
                    NoSt = 1;
                end
            end 
            end
            %Notification for signal existence in the model
            if (Nosl && NoGt && NoFm && NoMexpl && NoTr && NoSt)
            app.EditField.Value = 'Error!!';
            app.TextArea.Value =  'Given Signal is not found in the Model';
            else
            app.EditField.Value = 'Renamed successfully';
            end
            catch e
            app.EditField.Value = 'Error!!';
            app.TextArea.Value = e.message;
            end
        end

        % Callback function
        function ResetModuleButton_2Pushed(app, event)
          app.ExcelSheetNameEditField.Value = '';
          app.EditField_2.Value = '';
          app.TextArea_3.Value = '';
        end

        % Button pushed function: ResetModuleButton_3
        function ResetModuleButton_3Pushed(app, event)
           
            app.OldSignalNameEditField.Value = '';
            app.NewSignalNameEditField.Value = '';
            app.EditField.Value = '';
            app.TextArea.Value = '';
        end

        % Button pushed function: PropagateSignalNamesButton
        function PropagateSignalNamesButtonPushed(app, event)
            try
            %Rename Inport signals
            port_handles = find_system(gcs, 'FindAll', 'on', 'SearchDepth', '1', 'BlockType', 'Inport');
            for k = 1:length(port_handles)
            port_handle = get_param(port_handles(k), 'PortHandles');
            line = get_param(port_handle.Outport, 'Line');
            set_param(line, 'Name', get_param(port_handles(k), 'Name'));
            end
            
            %Rename Outport Signals
            port_handles = find_system(gcs, 'FindAll', 'on', 'SearchDepth', '1', 'BlockType', 'Outport');
            for k = 1:length(port_handles)
            port_handle = get_param(port_handles(k), 'PortHandles');
            line = get_param(port_handle.Inport, 'Line');
            set_param(line, 'Name', get_param(port_handles(k), 'Name'));
            end
            
            signallines = find_system(gcs, 'FindAll', 'on', 'type', 'line');
            for i = 1:length(signallines)
                 set(signallines(i),'signalPropagation','On');
            end
            app.EditField_3.Value = 'Propogated successfully';
            catch e
            app.EditField_3.Value = 'Error!!';
            app.TextArea_2.Value = e.message;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalRenameTool = uifigure;
            app.SignalRenameTool.Name = 'SignalRenamingTool';
            app.TabGroup = uitabgroup(app.SignalRenameTool);
            app.IndividualSignalRenamingTab = uitab(app.TabGroup);
            app.IndividualSignalRenamingTab.Title = 'Individual Signal Renaming';
            app.OldSignalNameEditFieldLabel = uilabel(app.IndividualSignalRenamingTab);
            app.OldSignalNameEditFieldLabel.Text = 'Old Signal Name :';
            app.OldSignalNameEditField = uieditfield(app.IndividualSignalRenamingTab, 'text');
            app.OldSignalNameEditField.ValueChangedFcn = createCallbackFcn(app, @OldSignalNameEditFieldValueChanged, true);
            app.NewSignalNameEditFieldLabel = uilabel(app.IndividualSignalRenamingTab);
            app.NewSignalNameEditFieldLabel.Text = 'New Signal Name :';
            app.NewSignalNameEditField = uieditfield(app.IndividualSignalRenamingTab, 'text');
            app.NewSignalNameEditField.ValueChangedFcn = createCallbackFcn(app, @NewSignalNameEditFieldValueChanged, true);
            app.RenameSignalNameButton = uibutton(app.IndividualSignalRenamingTab, 'push');
            app.RenameSignalNameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameSignalNameButtonPushed, true);
            app.RenameSignalNameButton.Text = 'Rename Signal Name';
            app.ResetModuleButton_3 = uibutton(app.IndividualSignalRenamingTab, 'push');
            app.ResetModuleButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetModuleButton_3Pushed, true);
            app.ResetModuleButton_3.Text = 'Reset Module';
            app.EditField = uieditfield(app.IndividualSignalRenamingTab, 'text');
            app.EditField.Editable = 'off';
            app.TextArea = uitextarea(app.IndividualSignalRenamingTab);
            app.GroupSignalRenamingTab = uitab(app.TabGroup);
            app.GroupSignalRenamingTab.Title = 'Group Signal Renaming';
            app.ExcelSheetNameLabel = uilabel(app.GroupSignalRenamingTab);
            app.ExcelSheetNameLabel.Text = 'Excel Sheet Name :';
            app.ExcelSheetNameEditField = uieditfield(app.GroupSignalRenamingTab, 'text');
            app.ExcelSheetNameEditField.ValueChangedFcn = createCallbackFcn(app, @ExcelSheetNameEditFieldValueChanged, true);
            app.RenameAllSignalsButton = uibutton(app.GroupSignalRenamingTab, 'push');
            app.RenameAllSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @RenameAllSignalsButtonPushed, true);
            app.RenameAllSignalsButton.Text = 'Rename All Signals';
            app.NoteTextAreaLabel = uilabel(app.GroupSignalRenamingTab);
            app.NoteTextAreaLabel.Text = 'Note:';
            app.NoteTextArea = uitextarea(app.GroupSignalRenamingTab);
            app.NoteTextArea.Editable = 'off';
            app.NoteTextArea.Value = {'1.Excel sheet should be in the current working folder.'; '(ExcelSheetName = Name.xlsx)'; ''; '2. Names should be in the First sheet of the excel workbook.'; '(SheetName  = ''Sheet1'')'; ''; '3. First column should have old names to be replaced'; '(beginning from A1 cell to An cell)'; ''; '4. Second column should have corresponding new names '; '(beginning from B1 cell to Bn cell)'};
            app.EditField_2 = uieditfield(app.GroupSignalRenamingTab, 'text');
            app.EditField_2.Editable = 'off';
            app.ResetModuleButton_2 = uibutton(app.GroupSignalRenamingTab, 'push');
            app.ResetModuleButton_2.Text = 'Reset Module';
            app.TextArea_3 = uitextarea(app.GroupSignalRenamingTab);
            app.SignalPropagationPanel = uipanel(app.SignalRenameTool);
            app.SignalPropagationPanel.Title = 'Signal Propagation';
            app.PropagateSignalNamesButton = uibutton(app.SignalPropagationPanel, 'push');
            app.PropagateSignalNamesButton.ButtonPushedFcn = createCallbackFcn(app, @PropagateSignalNamesButtonPushed, true);
            app.PropagateSignalNamesButton.Text = 'Propagate Signal Names';
            app.EditField_3 = uieditfield(app.SignalPropagationPanel, 'text');
            app.EditField_3.Editable = 'off';
            app.TextArea_2 = uitextarea(app.SignalPropagationPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = SignalRenamingTool

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalRenameTool)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalRenameTool)
        end
    end
end